classdef dsc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        title                          matlab.ui.control.Label
        start_button                   matlab.ui.control.Button
        cancel_button                  matlab.ui.control.Button
        input_paramters                matlab.ui.container.Panel
        rho_input                      matlab.ui.control.EditField
        r2_star_input                  matlab.ui.control.EditField
        TR_input                       matlab.ui.control.EditField
        TE_input                       matlab.ui.control.EditField
        oi_or_psvd_input               matlab.ui.control.EditField
        text5                          matlab.ui.control.Label
        text6                          matlab.ui.control.Label
        text7                          matlab.ui.control.Label
        text8                          matlab.ui.control.Label
        text9                          matlab.ui.control.Label
        text14                         matlab.ui.control.Label
        text15                         matlab.ui.control.Label
        text16                         matlab.ui.control.Label
        text17                         matlab.ui.control.Label
        deconvolution_algorithm_group  matlab.ui.container.ButtonGroup
        osvd                           matlab.ui.control.RadioButton
        ssvd                           matlab.ui.control.RadioButton
        percent                        matlab.ui.control.Label
        image_selection                matlab.ui.container.Panel
        dsc_image_path                 matlab.ui.control.EditField
        remove_images                  matlab.ui.control.Button
        add_images                     matlab.ui.control.Button
        define_aif                     matlab.ui.container.Panel
        aif_roi_path_gui               matlab.ui.control.EditField
        add_aif_roi                    matlab.ui.control.Button
        aif_selection                  matlab.ui.container.ButtonGroup
        auto_cluster                   matlab.ui.control.RadioButton
        user_aif                       matlab.ui.control.RadioButton
        import_aif_button              matlab.ui.control.RadioButton
        import_stored                  matlab.ui.control.RadioButton
        import_aif_path_gui            matlab.ui.control.EditField
        add_import_aif                 matlab.ui.control.Button
        status                         matlab.ui.control.Label
        noise_handling                 matlab.ui.container.Panel
        noise_selection                matlab.ui.container.ButtonGroup
        auto_noise                     matlab.ui.control.RadioButton
        user_noise                     matlab.ui.control.RadioButton
        add_noise_roi                  matlab.ui.control.Button
        noise_roi_path_gui             matlab.ui.control.EditField
        fitting_function_group         matlab.ui.container.ButtonGroup
        rawdata                        matlab.ui.control.RadioButton
        biexponential                  matlab.ui.control.RadioButton
        biexl                          matlab.ui.control.RadioButton
        Gammav                         matlab.ui.control.RadioButton
        upslopebiexp                   matlab.ui.control.RadioButton
        upslopebiexpliadj              matlab.ui.control.RadioButton
        species_selection              matlab.ui.container.ButtonGroup
        mouse_button                   matlab.ui.control.RadioButton
        human_button                   matlab.ui.control.RadioButton
        BolusInjectionTimeButtonGroup  matlab.ui.container.ButtonGroup
        bolus_user_selected            matlab.ui.control.RadioButton
        bolus_automatic                matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        dsc_image           % path to raw DSC images
        aif_type            % integer specifying aif type
        fitting_function    % integer specifying fitting function
        noise_type          % integer specify noise type
        aif_path            % path to nifiti or matlab file with AIF
        noise_roi_path      % path to noise roi file
        deconvolution_algorithm %integer specifying deconvolution algorithm
        bolus_detection     % integer specifying bolus injection time detection method
    end
    
    methods (Access = private)
        
        function update_aif_selection(app)
            switch app.aif_selection.SelectedObject.Tag
                case 'auto_cluster'
                   app.aif_type =  0;
                   app.aif_roi_path_gui.Enable = false;
                   app.add_aif_roi.Enable = false;
                   app.import_aif_path_gui.Enable = false;
                   app.add_import_aif.Enable = false;
                case 'user_aif'
                   app.aif_type = 1;
                   app.aif_roi_path_gui.Enable = true;
                   app.add_aif_roi.Enable = true;
                   app.import_aif_path_gui.Enable = false;
                   app.add_import_aif.Enable = false;
                case 'import_aif'
                   app.aif_type = 2;
                   app.aif_roi_path_gui.Enable = false;
                   app.add_aif_roi.Enable = false;
                   app.import_aif_path_gui.Enable = true;
                   app.add_import_aif.Enable = true;
                case 'import_stored'
                   app.aif_type = 3;
                   app.aif_roi_path_gui.Enable = false;
                   app.add_aif_roi.Enable = false;
                   app.import_aif_path_gui.Enable = false;
                   app.add_import_aif.Enable = false;
            end
        end
        
        function update_noise_selection(app)
            switch app.noise_selection.SelectedObject.Tag
                case 'auto_noise'
                    app.noise_type =  0;
                    app.noise_roi_path_gui.Enable = false;
                    app.add_noise_roi.Enable = false;
                case 'user_noise'
                    app.noise_type = 1;
                    app.noise_roi_path_gui.Enable = true;
                    app.add_noise_roi.Enable = true;
            end
            
        end
        
        function update_fitting_selection(app)
            switch app.fitting_function_group.SelectedObject.Tag
                case 'biexp'
                    app.fitting_function = 0;
                case 'biexl'
                    app.fitting_function = 1;
                case 'gammav'
                    app.fitting_function = 2;
                case 'rawdata'
                    app.fitting_function = 3;
                case 'upslopebiexpliadj'
                    app.fitting_function = 4;
                case 'upslopebiexp'
                    app.fitting_function = 5;
            end
            
        end
        
        function update_deconvolution_selection(app)
            
            switch app.deconvolution_algorithm_group.SelectedObject.Tag
                case 'osvd'
                    app.deconvolution_algorithm = 0;
                    
                    %display OI for oSVD and Psvd for sSVD
                    app.text9.Text = "OI";
                    app.percent.Text = " ";
          
                case 'ssvd'
                    app.deconvolution_algorithm = 1;
                    
                    %display OI for oSVD and Psvd for sSVD
                    app.text9.Text = "Psvd";
                    app.percent.Text = "%";
            end
             
        end
        
        function upadate_bolus_selection(app)
            
            switch app.BolusInjectionTimeButtonGroup.SelectedObject.Tag
                case 'bolus_automatic'
                    app.bolus_detection = 0;
                case 'bolus_user_selected'
                    app.bolus_detection = 1;
            end
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DSC_gui_OpeningFcn(app, varargin)
            uirestore(app.TE_input,struct('prop','Value'));
            uirestore(app.TR_input,struct('prop','Value'));
            uirestore(app.oi_or_psvd_input,struct('prop','Value'));
            uirestore(app.r2_star_input,struct('prop','Value'));
            uirestore(app.rho_input,struct('prop','Value'));
            %AIF radio buttons
            uirestore(app.auto_cluster,struct('prop','Value'));
            uirestore(app.user_aif,struct('prop','Value'));
            uirestore(app.import_aif_button,struct('prop','Value'));
            uirestore(app.import_stored,struct('prop','Value'));
            update_aif_selection(app)
            %Species radio buttons
            uirestore(app.mouse_button,struct('prop','Value'));
            uirestore(app.human_button,struct('prop','Value'));
            %Noise radio buttons
            uirestore(app.auto_noise,struct('prop','Value'));
            uirestore(app.user_noise,struct('prop','Value'));
            update_noise_selection(app)
            %Fitting radio buttons
            uirestore(app.rawdata,struct('prop','Value'));
            uirestore(app.biexponential,struct('prop','Value'));
            uirestore(app.biexl,struct('prop','Value'));
            uirestore(app.Gammav,struct('prop','Value'));
            uirestore(app.upslopebiexp,struct('prop','Value'));
            uirestore(app.upslopebiexpliadj,struct('prop','Value'));
            update_fitting_selection(app)
            %Algorithm radio buttons 
            uirestore(app.osvd,struct('prop','Value'));
            uirestore(app.ssvd,struct('prop','Value'));
            update_deconvolution_selection(app)
            %Bolus detection radio buttons
            uirestore(app.bolus_user_selected,struct('prop','Value'));
            uirestore(app.bolus_automatic,struct('prop','Value'));
            upadate_bolus_selection(app)
            
        end

        % Selection changed function: fitting_function_group
        function fitting_function_group_SelectionChangedFcn(app, event)
            update_fitting_selection(app)
         
            uiremember(app.rawdata,struct('prop','Value'));
            uiremember(app.biexponential,struct('prop','Value'));
            uiremember(app.biexl,struct('prop','Value'));
            uiremember(app.Gammav,struct('prop','Value'));
            uiremember(app.upslopebiexp,struct('prop','Value'));
            uiremember(app.upslopebiexpliadj,struct('prop','Value'));
        end

        % Value changed function: oi_or_psvd_input
        function oi_or_psvd_input_Callback(app, event)
            uiremember(app.oi_or_psvd_input,struct('prop','Value'));
        end

        % Button pushed function: start_button
        function start_button_Callback(app, event)
            app.status.Visible = true;
            
            % Check inputs
            if app.noise_type == 1
                if exist(app.noise_roi_path, 'file') ~= 2
                    inputs_good = false;
                    app.status.Text = 'No noise image selected';
                end
            end
            if app.aif_type == 1 || app.aif_type == 2
                if exist(app.aif_path, 'file') ~= 2
                    inputs_good = false;
                    app.status.Text = 'No AIF file selected';
                end
            end
            if app.aif_type == 3
                if exist('previous_data.mat', 'file') ~= 2
                    inputs_good = false;
                    app.status.Text = 'Cannot use previous AIF, no "previous_data.mat" file exists';
                end
            end
            inputs_good = true;
            if exist(app.dsc_image, 'file') ~= 2
                inputs_good = false;
                app.status.Text = 'No DSC image selected';
            end
            
            if inputs_good
                app.status.Text = 'Processing Data...';
                
                %Unboxing the variables from the handles structure:
                TE = str2double(get(app.TE_input,'Value'))/1000.0; %conversion from miliseconds to seconds 12/15/17
                TR = str2double(get(app.TR_input,'Value'))/1000.0; %conversion from miliseconds to seconds 6/12/20
                r2_star = str2double(get(app.r2_star_input,'Value'));
                species = app.species_selection.SelectedObject.Tag;
                oi_or_psvd = str2double(get(app.oi_or_psvd_input,'Value'));
                rho =  str2double(get(app.rho_input,'Value'));
                
                dsc_process(app.dsc_image,app.noise_type,app.noise_roi_path, ...
                    app.aif_type,app.aif_path,app.fitting_function,TE,TR,...
                    r2_star,oi_or_psvd,rho,species,app.deconvolution_algorithm,app.bolus_detection)
            
                app.status.Text = 'Completed Processing';
                closereq()
            end
        end

        % Value changed function: TE_input
        function TE_input_Callback(app, event)
            uiremember(app.TE_input,struct('prop','Value'));
        end

        % Value changed function: TR_input
        function TR_input_Callback(app, event)
            uiremember(app.TR_input,struct('prop','Value'));
        end

        % Button pushed function: add_aif_roi
        function add_aif_roi_Callback(app, event)
            % When this button gets pressed, open a dialog window to select a file:
            [filename, pathname, filterspec] = uigetfile('*.nii','Nifti Files (*.nii)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                app.aif_roi_path_gui.Value = visualpath;
                app.aif_path = path_out;
            
            end
        end

        % Button pushed function: add_images
        function add_images_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to add_images (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % When this button gets pressed, open a dialog window to select a file:
            [filename, pathname, filterspec] = uigetfile('*.nii','Nifti Files (*.nii)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                set(app.dsc_image_path,'Value',visualpath);
                app.dsc_image = path_out;
            
            end
            
            %guidata(hObject, handles);
        end

        % Button pushed function: add_import_aif
        function add_import_aif_Callback(app, event)
            % When this button gets pressed, open a dialog window to select a file:
            [filename, pathname, filterspec] = uigetfile('*.mat','Matlab Files (*.mat)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                app.import_aif_path_gui.Value = visualpath;
                app.aif_path = path_out;
            end
        end

        % Button pushed function: add_noise_roi
        function add_noise_roi_Callback(app, event)
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname, filterspec] = uigetfile('*.nii','Nifti Files (*.nii)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                app.noise_roi_path_gui.Value = visualpath;
                app.noise_roi_path = path_out;
            end
        end

        % Selection changed function: aif_selection
        function aif_selection_SelectionChangeFcn(app, event)
            update_aif_selection(app)
            
            uiremember(app.auto_cluster,struct('prop','Value'));
            uiremember(app.user_aif,struct('prop','Value'));
            uiremember(app.import_aif_button,struct('prop','Value'));
            uiremember(app.import_stored,struct('prop','Value'));
        end

        % Value changed function: noise_roi_path_gui
        function noise_roi_path_gui_Callback(app, event)
            value = app.noise_roi_path_gui.Value;
            
        end

        % Selection changed function: noise_selection
        function noise_selection_SelectionChangeFcn(app, event)
            update_noise_selection(app)
            
            uiremember(app.auto_noise,struct('prop','Value'));
            uiremember(app.user_noise,struct('prop','Value'));
        end

        % Value changed function: r2_star_input
        function r2_star_input_Callback(app, event)
            uiremember(app.r2_star_input,struct('prop','Value'));
        end

        % Button pushed function: remove_images
        function remove_images_Callback(app, event)
            app.dsc_image_path.Value = '';
            app.dsc_image = [];
        end

        % Value changed function: rho_input
        function rho_input_Callback(app, event)
            uiremember(app.rho_input,struct('prop','Value'));
        end

        % Selection changed function: species_selection
        function species_selection_SelectionChangeFcn(app, event)
            uiremember(app.mouse_button,struct('prop','Value'));
            uiremember(app.human_button,struct('prop','Value'));
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)

            %uiremember(app.TE_input,struct('prop','Value'))
            delete(app)
        end

        % Button pushed function: cancel_button
        function cancel_buttonButtonPushed(app, event)
            closereq()
        end

        % Selection changed function: deconvolution_algorithm_group
        function deconvolution_algorithm_groupSelectionChanged(app, event)
            update_deconvolution_selection(app)
            
            uiremember(app.osvd,struct('prop','Value'));
            uiremember(app.ssvd,struct('prop','Value'));
       
        end

        % Selection changed function: BolusInjectionTimeButtonGroup
        function BolusInjectionTimeButtonGroupSelectionChanged(app, event)
            upadate_bolus_selection(app)
            
            uiremember(app.bolus_user_selected,struct('prop','Value'));
            uiremember(app.bolus_automatic,struct('prop','Value'));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DSC_gui';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.title = uilabel(app.figure1);
            app.title.Tag = 'text3';
            app.title.Text = 'DSC Analysis GUI';
            app.start_button = uibutton(app.figure1, 'push');
            app.start_button.ButtonPushedFcn = createCallbackFcn(app, @start_button_Callback, true);
            app.start_button.Tag = 'Start_button';
            app.start_button.Text = 'Start Analysis';
            app.cancel_button = uibutton(app.figure1, 'push');
            app.cancel_button.ButtonPushedFcn = createCallbackFcn(app, @cancel_buttonButtonPushed, true);
            app.cancel_button.Tag = 'Cancel_button';
            app.cancel_button.Text = 'Cancel';
            app.input_paramters = uipanel(app.figure1);
            app.input_paramters.Title = 'Input Parameters';
            app.input_paramters.Tag = 'uipanel3';
            app.rho_input = uieditfield(app.input_paramters, 'text');
            app.rho_input.ValueChangedFcn = createCallbackFcn(app, @rho_input_Callback, true);
            app.rho_input.Tag = 'rho_input';
            app.rho_input.Value = '1';
            app.r2_star_input = uieditfield(app.input_paramters, 'text');
            app.r2_star_input.ValueChangedFcn = createCallbackFcn(app, @r2_star_input_Callback, true);
            app.r2_star_input.Tag = 'r2_star_input';
            app.r2_star_input.Value = '1.05';
            app.TR_input = uieditfield(app.input_paramters, 'text');
            app.TR_input.ValueChangedFcn = createCallbackFcn(app, @TR_input_Callback, true);
            app.TR_input.Tag = 'TR_input';
            app.TR_input.Value = '1';
            app.TE_input = uieditfield(app.input_paramters, 'text');
            app.TE_input.ValueChangedFcn = createCallbackFcn(app, @TE_input_Callback, true);
            app.TE_input.Tag = 'TE_input';
            app.TE_input.Value = '19';
            app.oi_or_psvd_input = uieditfield(app.input_paramters, 'text');
            app.oi_or_psvd_input.ValueChangedFcn = createCallbackFcn(app, @oi_or_psvd_input_Callback, true);
            app.oi_or_psvd_input.Tag = 'oi_or_psvd_input';
            app.oi_or_psvd_input.Value = '0.005';
            app.text5 = uilabel(app.input_paramters);
            app.text5.Tag = 'text5';
            app.text5.Text = 'TE';
            app.text6 = uilabel(app.input_paramters);
            app.text6.Tag = 'text6';
            app.text6.Text = 'TR';
            app.text7 = uilabel(app.input_paramters);
            app.text7.Tag = 'text7';
            app.text7.Text = 'R2*';
            app.text8 = uilabel(app.input_paramters);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Rho';
            app.text9 = uilabel(app.input_paramters);
            app.text9.Tag = 'text9';
            app.text9.Text = 'OI';
            app.text14 = uilabel(app.input_paramters);
            app.text14.Tag = 'text14';
            app.text14.Text = 'ms';
            app.text15 = uilabel(app.input_paramters);
            app.text15.Tag = 'text15';
            app.text15.Text = 'ms';
            app.text16 = uilabel(app.input_paramters);
            app.text16.Tag = 'text16';
            app.text16.Text = 'L/(mmol·s)';
            app.text17 = uilabel(app.input_paramters);
            app.text17.Tag = 'text17';
            app.text17.Text = 'g/ml';
            app.deconvolution_algorithm_group = uibuttongroup(app.input_paramters);
            app.deconvolution_algorithm_group.SelectionChangedFcn = createCallbackFcn(app, @deconvolution_algorithm_groupSelectionChanged, true);
            app.deconvolution_algorithm_group.Title = 'Deconvolution Algorithm';
            app.osvd = uiradiobutton(app.deconvolution_algorithm_group);
            app.osvd.Tag = 'osvd';
            app.osvd.Text = 'oSVD';
            app.osvd.Value = true;
            app.ssvd = uiradiobutton(app.deconvolution_algorithm_group);
            app.ssvd.Tag = 'ssvd';
            app.ssvd.Text = 'sSVD';
            app.percent = uilabel(app.input_paramters);
            app.percent.Text = '%';
            app.image_selection = uipanel(app.figure1);
            app.image_selection.Title = 'Image Selection';
            app.image_selection.Tag = 'uipanel8';
            app.dsc_image_path = uieditfield(app.image_selection, 'text');
            app.dsc_image_path.Tag = 'dsc_image_path';
            app.dsc_image_path.Value = 'DSC Image Path (.nii files only)';
            app.remove_images = uibutton(app.image_selection, 'push');
            app.remove_images.ButtonPushedFcn = createCallbackFcn(app, @remove_images_Callback, true);
            app.remove_images.Tag = 'remove_images';
            app.remove_images.Text = 'Remove Image';
            app.add_images = uibutton(app.image_selection, 'push');
            app.add_images.ButtonPushedFcn = createCallbackFcn(app, @add_images_Callback, true);
            app.add_images.Tag = 'add_images';
            app.add_images.Text = 'Add Image';
            app.define_aif = uipanel(app.figure1);
            app.define_aif.Title = 'Define AIF';
            app.define_aif.Tag = 'uipanel12';
            app.aif_roi_path_gui = uieditfield(app.define_aif, 'text');
            app.aif_roi_path_gui.Tag = 'AIF_path';
            app.aif_roi_path_gui.Enable = 'off';
            app.aif_roi_path_gui.Value = 'AIF Filepath (.nii files only; same size as Image)';
            app.add_aif_roi = uibutton(app.define_aif, 'push');
            app.add_aif_roi.ButtonPushedFcn = createCallbackFcn(app, @add_aif_roi_Callback, true);
            app.add_aif_roi.Tag = 'add_AIF_roi';
            app.add_aif_roi.Enable = 'off';
            app.add_aif_roi.Text = 'Select AIF ROI file (or enter below)';
            app.aif_selection = uibuttongroup(app.define_aif);
            app.aif_selection.SelectionChangedFcn = createCallbackFcn(app, @aif_selection_SelectionChangeFcn, true);
            app.aif_selection.Title = 'AIF Selection Type';
            app.aif_selection.Tag = 'aif_selection';
            app.auto_cluster = uiradiobutton(app.aif_selection);
            app.auto_cluster.Tag = 'auto_cluster';
            app.auto_cluster.Text = 'Auto Cluster AIF';
            app.auto_cluster.Value = true;
            app.user_aif = uiradiobutton(app.aif_selection);
            app.user_aif.Tag = 'user_aif';
            app.user_aif.Text = 'User Selected AIF';
            app.import_aif_button = uiradiobutton(app.aif_selection);
            app.import_aif_button.Tag = 'import_aif';
            app.import_aif_button.Text = 'Import AIF';
            app.import_stored = uiradiobutton(app.aif_selection);
            app.import_stored.Tag = 'import_stored';
            app.import_stored.Text = 'Use AIF from Previous Run';
            app.import_aif_path_gui = uieditfield(app.define_aif, 'text');
            app.import_aif_path_gui.Enable = 'off';
            app.import_aif_path_gui.Value = 'AIF Filepath (.mat file)';
            app.add_import_aif = uibutton(app.define_aif, 'push');
            app.add_import_aif.ButtonPushedFcn = createCallbackFcn(app, @add_import_aif_Callback, true);
            app.add_import_aif.Tag = 'add_AIF_roi';
            app.add_import_aif.Enable = 'off';
            app.add_import_aif.Text = 'Select Import AIF file (or enter below)';
            app.status = uilabel(app.figure1);
            app.status.Tag = 'text21';
            app.status.Visible = 'off';
            app.status.Text = 'Processing Data...';
            app.noise_handling = uipanel(app.figure1);
            app.noise_handling.Title = 'Noise Handling';
            app.noise_handling.Tag = 'uipanel14';
            app.noise_selection = uibuttongroup(app.noise_handling);
            app.noise_selection.SelectionChangedFcn = createCallbackFcn(app, @noise_selection_SelectionChangeFcn, true);
            app.noise_selection.Title = 'Noise Region Selection';
            app.noise_selection.Tag = 'noise_selection';
            app.auto_noise = uiradiobutton(app.noise_selection);
            app.auto_noise.Tag = 'auto_noise';
            app.auto_noise.Text = 'Automatic';
            app.auto_noise.Value = true;
            app.user_noise = uiradiobutton(app.noise_selection);
            app.user_noise.Tag = 'user_noise';
            app.user_noise.Text = 'User Defined';
            app.add_noise_roi = uibutton(app.noise_handling, 'push');
            app.add_noise_roi.ButtonPushedFcn = createCallbackFcn(app, @add_noise_roi_Callback, true);
            app.add_noise_roi.Tag = 'add_noise_roi';
            app.add_noise_roi.Enable = 'off';
            app.add_noise_roi.Text = 'Select Noise ROI file';
            app.noise_roi_path_gui = uieditfield(app.noise_handling, 'text');
            app.noise_roi_path_gui.ValueChangedFcn = createCallbackFcn(app, @noise_roi_path_gui_Callback, true);
            app.noise_roi_path_gui.Tag = 'noise_file';
            app.noise_roi_path_gui.Enable = 'off';
            app.noise_roi_path_gui.Value = 'Noise File (.nii; same size as image)';
            app.fitting_function_group = uibuttongroup(app.figure1);
            app.fitting_function_group.SelectionChangedFcn = createCallbackFcn(app, @fitting_function_group_SelectionChangedFcn, true);
            app.fitting_function_group.Title = 'Fitting Function';
            app.fitting_function_group.Tag = 'Fitting_function';
            app.rawdata = uiradiobutton(app.fitting_function_group);
            app.rawdata.Tag = 'rawdata';
            app.rawdata.Text = 'Raw Data';
            app.biexponential = uiradiobutton(app.fitting_function_group);
            app.biexponential.Tag = 'biexp';
            app.biexponential.Text = 'Biexponential';
            app.biexl = uiradiobutton(app.fitting_function_group);
            app.biexl.Tag = 'biexl';
            app.biexl.Text = 'Biexponential local';
            app.Gammav = uiradiobutton(app.fitting_function_group);
            app.Gammav.Tag = 'gammav';
            app.Gammav.Text = 'Gamma-Variant';
            app.Gammav.Value = true;
            app.upslopebiexp = uiradiobutton(app.fitting_function_group);
            app.upslopebiexp.Tag = 'upslopebiexp';
            app.upslopebiexp.Text = 'Upslope copy biexponential';
            app.upslopebiexpliadj = uiradiobutton(app.fitting_function_group);
            app.upslopebiexpliadj.Tag = 'upslopebiexpliadj';
            app.upslopebiexpliadj.Text = 'Upslope copy biexponential linear adjustment';
            app.species_selection = uibuttongroup(app.figure1);
            app.species_selection.SelectionChangedFcn = createCallbackFcn(app, @species_selection_SelectionChangeFcn, true);
            app.species_selection.Title = 'Species';
            app.species_selection.Tag = 'species_selection';
            app.mouse_button = uiradiobutton(app.species_selection);
            app.mouse_button.Tag = 'mouse';
            app.mouse_button.Text = 'Mouse';
            app.mouse_button.Value = true;
            app.human_button = uiradiobutton(app.species_selection);
            app.human_button.Tag = 'human';
            app.human_button.Text = 'Human';
            app.BolusInjectionTimeButtonGroup = uibuttongroup(app.figure1);
            app.BolusInjectionTimeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BolusInjectionTimeButtonGroupSelectionChanged, true);
            app.BolusInjectionTimeButtonGroup.Title = 'Bolus Injection Time ';
            app.bolus_user_selected = uiradiobutton(app.BolusInjectionTimeButtonGroup);
            app.bolus_user_selected.Tag = 'bolus_automatic';
            app.bolus_user_selected.Text = 'Automatic';
            app.bolus_user_selected.Value = true;
            app.bolus_automatic = uiradiobutton(app.BolusInjectionTimeButtonGroup);
            app.bolus_automatic.Tag = 'bolus_user_selected';
            app.bolus_automatic.Text = 'User Selected';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dsc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)DSC_gui_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end